classdef imagegeneratorclassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        imagegeneratorclassifierUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CompositeImageGeneratorTab    matlab.ui.container.Tab
        StandardInputandOutputPanel   matlab.ui.container.Panel
        InputSourceImagesButton       matlab.ui.control.Button
        GotoClassSpecificInputandOutputButton  matlab.ui.control.Button
        InputImageButton              matlab.ui.control.Button
        GoButton                      matlab.ui.control.Button
        OptionsPanel                  matlab.ui.container.Panel
        TileCountEditFieldLabel       matlab.ui.control.Label
        TileCountEditField            matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        CompInfo                      matlab.ui.control.EditField
        ClassSpecificInputandOutputPanel  matlab.ui.container.Panel
        GotoStandardInputandOutputButton  matlab.ui.control.Button
        InputImageButton_2            matlab.ui.control.Button
        ManmadeTrainingButton_2       matlab.ui.control.Button
        NaturalTrainingButton_2       matlab.ui.control.Button
        BinaryImageClassifierTab      matlab.ui.container.Tab
        TrainingImageLocationsPanel   matlab.ui.container.Panel
        ManmadeTrainingButton         matlab.ui.control.Button
        NaturalTrainingButton         matlab.ui.control.Button
        TestImageLocationsPanel       matlab.ui.container.Panel
        ManmadeTestButton             matlab.ui.control.Button
        NaturalTestButton             matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        InfoTextAreaLabel             matlab.ui.control.Label
        InfoTextArea                  matlab.ui.control.TextArea
        kEditFieldLabel               matlab.ui.control.Label
        kEditField                    matlab.ui.control.NumericEditField
        HelpTab                       matlab.ui.container.Tab
        CompositeImageGeneratorPanel  matlab.ui.container.Panel
        TextArea_2                    matlab.ui.control.TextArea
        BinaryImageClassifierPanel    matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        EditField                     matlab.ui.control.EditField
    end


    properties (Access = private)
        % COMPOSITE IMAGE GENERATOR
        % put properties here
        targetFile='';
        sourceDir='';
        numTiles=1;
        width=1;
        height=1;
        % BINARY IMAGE CLASSIFIER 
        trainManmadeDir = '';
        trainNaturalDir = '';
        testManmadeDir = '';
        testNaturalDir = '';
        kVal = 4;
    end

    methods (Access = private)
    
        function appendTextArea(app, text)
             app.InfoTextArea.Value = [app.InfoTextArea.Value; text];
             buttonStatus(app);
        end
        
        function buttonStatus(app)
            if isempty(app.trainManmadeDir) || isempty(app.trainNaturalDir) || isempty(app.testManmadeDir) || isempty(app.testNaturalDir)
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('classification-toolkit'));
            addpath(genpath('generator-toolkit'));
            app.ClassSpecificInputandOutputPanel.Visible = 'off';
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.CompInfo.Value='Processing...';
            if strcmp(app.ClassSpecificInputandOutputPanel.Visible, 'on')
                classificationDirectory = '';
                classType = '';
                 [manmadeCount, naturalCount, timeTaken] = CalculateDifference(app.trainManmadeDir, app.trainNaturalDir, app.targetFile, 'na', app.kVal);
                 if (manmadeCount > naturalCount)
                     app.sourceDir = app.trainManmadeDir;
                     classType = 'Manmade';
                 else
                     app.sourceDir = app.trainNaturalDir;
                     classType = 'Natural';
                 end
                 [~,timer,avgRGBDIf]=RunApp(app.sourceDir,app.targetFile,app.numTiles, app.width, app.height);
                 app.CompInfo.Value=['Input image was classified as ', classType, ', Done in ',num2str(timer+timeTaken), ' seconds with a colour difference of ',num2str(avgRGBDIf)];
            else
                [~,timer,avgRGBDIf]=RunApp(app.sourceDir,app.targetFile,app.numTiles, app.width, app.height);
                app.CompInfo.Value=['Done in ',num2str(timer), ' seconds with a colour difference of ',num2str(avgRGBDIf)];
            end
        end

        % Button pushed function: 
        % GotoClassSpecificInputandOutputButton
        function GotoClassSpecificInputandOutputButtonPushed(app, event)
            app.ClassSpecificInputandOutputPanel.Visible = 'on';
            app.StandardInputandOutputPanel.Visible = 'off';
        end

        % Button pushed function: GotoStandardInputandOutputButton
        function GotoStandardInputandOutputButtonPushed(app, event)
            app.ClassSpecificInputandOutputPanel.Visible = 'off';
            app.StandardInputandOutputPanel.Visible = 'on';
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            app.height = app.HeightEditField.Value;
            
        end

        % Value changed function: InfoTextArea
        function InfoTextAreaValueChanged(app, event)
            %val
            %ue = app.InfoTextArea.Value;
        end

        % Button pushed function: InputImageButton, InputImageButton_2
        function InputImageButtonPushed(app, event)
            [filename,pathname]= uigetfile('.jpg');
            app.targetFile = fullfile(pathname, filename);
        end

        % Button pushed function: InputSourceImagesButton
        function InputSourceImagesButtonPushed(app, event)
            app.sourceDir = uigetdir('C:\');
        end

        % Button pushed function: ManmadeTestButton
        function ManmadeTestButtonPushed(app, event)
            app.testManmadeDir = uigetdir('C:\');
            appendTextArea(app, ['Manmade test directory: ' app.testManmadeDir]);
        end

        % Button pushed function: ManmadeTrainingButton, 
        % ManmadeTrainingButton_2
        function ManmadeTrainingButtonPushed(app, event)
            app.trainManmadeDir = uigetdir('C:\');
            appendTextArea(app, ['Manmade training directory: ' app.trainManmadeDir]);
        end

        % Button pushed function: NaturalTestButton
        function NaturalTestButtonPushed(app, event)
            app.testNaturalDir = uigetdir('C:\');
            appendTextArea(app, ['Natural test directory: ' app.testNaturalDir]);
        end

        % Button pushed function: NaturalTrainingButton, 
        % NaturalTrainingButton_2
        function NaturalTrainingButtonPushed(app, event)
            app.trainNaturalDir = uigetdir('C:\');
            appendTextArea(app, ['Natural training directory: ' app.trainNaturalDir]);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.InfoTextArea.Value = [app.InfoTextArea.Value;  'Processing Begun!'];
            app.StartButton.Enable = 'off';
            [manmadeCount, naturalCount, timeTaken] = CalculateDifference(app.trainManmadeDir, app.trainNaturalDir, app.testManmadeDir, app.testNaturalDir, app.kVal);
            app.StartButton.Enable = 'on';
            app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Processing Finished!'; 'Manmade Classification Accuracy: ' num2str(manmadeCount) '%'; 'Natural Classification Accuracy: ' num2str(naturalCount) '%'; 'Total Accuracy: ' num2str((manmadeCount + naturalCount)/2) '%'; num2str(timeTaken) ' seconds taken'];
        end

        % Value changed function: TileCountEditField
        function TileCountEditFieldValueChanged(app, event)
            app.numTiles = app.TileCountEditField.Value;
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.width = app.WidthEditField.Value;
            
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            app.kVal = app.kEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.imagegeneratorclassifierUIFigure = uifigure;
            app.imagegeneratorclassifierUIFigure.Name = 'image-generator-classifier';
            app.TabGroup = uitabgroup(app.imagegeneratorclassifierUIFigure);
            app.CompositeImageGeneratorTab = uitab(app.TabGroup);
            app.CompositeImageGeneratorTab.Title = 'Composite Image Generator';
            app.StandardInputandOutputPanel = uipanel(app.CompositeImageGeneratorTab);
            app.StandardInputandOutputPanel.Title = 'Standard Input and Output';
            app.InputSourceImagesButton = uibutton(app.StandardInputandOutputPanel, 'push');
            app.InputSourceImagesButton.ButtonPushedFcn = createCallbackFcn(app, @InputSourceImagesButtonPushed, true);
            app.InputSourceImagesButton.Text = 'Input Source Images';
            app.GotoClassSpecificInputandOutputButton = uibutton(app.StandardInputandOutputPanel, 'push');
            app.GotoClassSpecificInputandOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GotoClassSpecificInputandOutputButtonPushed, true);
            app.GotoClassSpecificInputandOutputButton.Text = 'Go to Class Specific Input and Output';
            app.InputImageButton = uibutton(app.StandardInputandOutputPanel, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.GoButton = uibutton(app.CompositeImageGeneratorTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.OptionsPanel = uipanel(app.CompositeImageGeneratorTab);
            app.OptionsPanel.Title = 'Options';
            app.TileCountEditFieldLabel = uilabel(app.OptionsPanel);
            app.TileCountEditFieldLabel.Text = 'Tile Count';
            app.TileCountEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.TileCountEditField.ValueChangedFcn = createCallbackFcn(app, @TileCountEditFieldValueChanged, true);
            app.TileCountEditField.Limits = [1 Inf];
            app.TileCountEditField.ValueDisplayFormat = '%11.9g';
            app.TileCountEditField.Value = 1;
            app.WidthEditFieldLabel = uilabel(app.OptionsPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Limits = [1 Inf];
            app.WidthEditField.ValueDisplayFormat = '%11.9g';
            app.WidthEditField.Value = 1;
            app.HeightEditFieldLabel = uilabel(app.OptionsPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.Value = 1;
            app.CompInfo = uieditfield(app.CompositeImageGeneratorTab, 'text');
            app.CompInfo.Editable = 'off';
            app.ClassSpecificInputandOutputPanel = uipanel(app.CompositeImageGeneratorTab);
            app.ClassSpecificInputandOutputPanel.Title = 'Class-Specific Input and Output';
            app.GotoStandardInputandOutputButton = uibutton(app.ClassSpecificInputandOutputPanel, 'push');
            app.GotoStandardInputandOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStandardInputandOutputButtonPushed, true);
            app.GotoStandardInputandOutputButton.Text = 'Go to Standard Input and Output';
            app.InputImageButton_2 = uibutton(app.ClassSpecificInputandOutputPanel, 'push');
            app.InputImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton_2.Text = 'Input Image';
            app.ManmadeTrainingButton_2 = uibutton(app.ClassSpecificInputandOutputPanel, 'push');
            app.ManmadeTrainingButton_2.ButtonPushedFcn = createCallbackFcn(app, @ManmadeTrainingButtonPushed, true);
            app.ManmadeTrainingButton_2.Text = 'Manmade Training';
            app.NaturalTrainingButton_2 = uibutton(app.ClassSpecificInputandOutputPanel, 'push');
            app.NaturalTrainingButton_2.ButtonPushedFcn = createCallbackFcn(app, @NaturalTrainingButtonPushed, true);
            app.NaturalTrainingButton_2.Text = 'Natural Training';
            app.BinaryImageClassifierTab = uitab(app.TabGroup);
            app.BinaryImageClassifierTab.Title = 'Binary Image Classifier';
            app.TrainingImageLocationsPanel = uipanel(app.BinaryImageClassifierTab);
            app.TrainingImageLocationsPanel.Title = 'Training Image Locations';
            app.ManmadeTrainingButton = uibutton(app.TrainingImageLocationsPanel, 'push');
            app.ManmadeTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @ManmadeTrainingButtonPushed, true);
            app.ManmadeTrainingButton.Text = 'Manmade Training';
            app.NaturalTrainingButton = uibutton(app.TrainingImageLocationsPanel, 'push');
            app.NaturalTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @NaturalTrainingButtonPushed, true);
            app.NaturalTrainingButton.Text = 'Natural Training';
            app.TestImageLocationsPanel = uipanel(app.BinaryImageClassifierTab);
            app.TestImageLocationsPanel.Title = 'Test Image Locations';
            app.ManmadeTestButton = uibutton(app.TestImageLocationsPanel, 'push');
            app.ManmadeTestButton.ButtonPushedFcn = createCallbackFcn(app, @ManmadeTestButtonPushed, true);
            app.ManmadeTestButton.Text = 'Manmade Test';
            app.NaturalTestButton = uibutton(app.TestImageLocationsPanel, 'push');
            app.NaturalTestButton.ButtonPushedFcn = createCallbackFcn(app, @NaturalTestButtonPushed, true);
            app.NaturalTestButton.Text = 'Natural Test';
            app.StartButton = uibutton(app.BinaryImageClassifierTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.InfoTextAreaLabel = uilabel(app.BinaryImageClassifierTab);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.BinaryImageClassifierTab);
            app.InfoTextArea.ValueChangedFcn = createCallbackFcn(app, @InfoTextAreaValueChanged, true);
            app.InfoTextArea.Editable = 'off';
            app.kEditFieldLabel = uilabel(app.BinaryImageClassifierTab);
            app.kEditFieldLabel.Text = 'k = ';
            app.kEditField = uieditfield(app.BinaryImageClassifierTab, 'numeric');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Limits = [1 25];
            app.kEditField.RoundFractionalValues = 'on';
            app.kEditField.ValueDisplayFormat = '%11.2g';
            app.kEditField.Value = 4;
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.CompositeImageGeneratorPanel = uipanel(app.HelpTab);
            app.CompositeImageGeneratorPanel.Title = 'Composite Image Generator';
            app.TextArea_2 = uitextarea(app.CompositeImageGeneratorPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'The composite image generator takes an input (or "target") image, and a list of images. These can then be used to create an image made up of many smaller images that resembles the target image.'; ''; 'To use the composite image generator you must first give the application some information. Use input buttons to select the target image and the source images that will be used to create this target image.  Then select the number of tiles you would like use to create your target image, and the width and height you would like the final composite image to be. Once all of your values have been selected click "Go!" to begin the process of creating a composite image.'; ''; 'If you would like to classify an image and create a composite image made up of either natural or manmade images based on this classification click on the "Go to Class Specific Input and Output" button. Here you will have to select the images you would like to use to train the classifier. These will also be the images used to make up the composite image'};
            app.BinaryImageClassifierPanel = uipanel(app.HelpTab);
            app.BinaryImageClassifierPanel.Title = 'Binary Image Classifier';
            app.TextArea = uitextarea(app.BinaryImageClassifierPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The binary image classifier allows you to to classify manmade and natural images according to an inputed training set.'; ''; 'To utilise this functionality, first select the manmade and natural training sets that you would like to use. Do this by selecting the directory that contains these images. After this, you will perform the same operation to select the manmade and natural test sets that you would like to use with the classifier.'; ''; 'The classifier operates using a k-nearest neighbour classifier. You may specify k yourself for any value from 1 to 25. The default value is 4. '; ''; 'Press the Start button to begin classification. When classification is complete the results box shall show you the total computation time, misclassification rates for each class and the total accuracy.'};
            app.EditField = uieditfield(app.imagegeneratorclassifierUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Team 3 - Iain Morton and Daniel Kinnaird                        https://github.com/AC41002-team3/image-generator-classifier';
        end
    end

    methods (Access = public)

        % Construct app
        function app = imagegeneratorclassifier

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.imagegeneratorclassifierUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.imagegeneratorclassifierUIFigure)
        end
    end
end